classdef Project_01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        LoadImageButton          matlab.ui.control.Button
        SaveImageButton          matlab.ui.control.Button
        ResetImageButton         matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        GridLayout2              matlab.ui.container.GridLayout
        BrightnessSliderLabel    matlab.ui.control.Label
        BrightnessSlider         matlab.ui.control.Slider
        EditField                matlab.ui.control.NumericEditField
        GridLayout5              matlab.ui.container.GridLayout
        UIAxesOriginalImage      matlab.ui.control.UIAxes
        UIAxesEditedImage        matlab.ui.control.UIAxes
        GridLayout6              matlab.ui.container.GridLayout
        UIAxesOriginalHistogram  matlab.ui.control.UIAxes
        UIAxesEditedHistogram    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global inputImageFile
            global originalGrayImage
            global grayImage
            
            %rawImage = imread('peppers.png');
            %grayImage = rgb2gray(rawImage);
            %imshow(grayImage,'Parent',app.UIAxesOriginalImage);

            [file,path] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            
            inputImageFile = strcat(path,file);
            disp(inputImageFile)
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            grayImage = rgb2gray(imread(inputImageFile));
            originalGrayImage = grayImage;
            imshow(grayImage,'Parent',app.UIAxesOriginalImage);
            imshow(grayImage,'Parent',app.UIAxesEditedImage);
            
            imh = imhist(originalGrayImage);
            ax = axes('Parent', imh);
            %app.UIAxesOriginalHistogram.
            axes(app.UIAxesOriginalHistogram);
            plot(ax);
            %imhist(grayImage,'Parent',app.UIAxesEditedHistogram)
        end

        % Value changed function: BrightnessSlider
        function BrightnessValChdFn(app, event)
            global grayImage
            global brightnessChangeValue;
            brightnessChangeValue = app.BrightnessSlider.Value;
            imshow(grayImage + brightnessChangeValue,'Parent',app.UIAxesEditedImage);
            %imhist(grayImage + brightnessChangeValue,'Parent',app.UIAxesEditedHistogram)
            %disp(brightnessChangeValue)
        end

        % Value changing function: BrightnessSlider
        function BrightnessValChgFn(app, event)
            global grayImage
            global brightnessChangeValue;
            brightnessChangeValue = event.Value;
            imshow(grayImage + brightnessChangeValue,'Parent',app.UIAxesEditedImage);
            %imhist(grayImage + brightnessChangeValue,'Parent',app.UIAxesEditedHistogram)
            %disp(brightnessChangeValue)
            app.EditField.Value = brightnessChangeValue;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            global brightnessChangeValue;
            disp(brightnessChangeValue)
            app.EditField.Value = brightnessChangeValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.LoadImageButton = uibutton(app.GridLayout3, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.GridLayout3, 'push');
            app.SaveImageButton.Text = 'Save Image';
            app.ResetImageButton = uibutton(app.GridLayout3, 'push');
            app.ResetImageButton.Text = 'Reset Image';
            app.Image = uiimage(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.BrightnessSliderLabel = uilabel(app.GridLayout2);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.GridLayout2);
            app.BrightnessSlider.Limits = [-128 127];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessValChdFn, true);
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessValChgFn, true);
            app.EditField = uieditfield(app.GridLayout2, 'numeric');
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%3.2g';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.UIAxesOriginalImage = uiaxes(app.GridLayout5);
            title(app.UIAxesOriginalImage, 'Original Image')
            xlabel(app.UIAxesOriginalImage, '')
            ylabel(app.UIAxesOriginalImage, '')
            app.UIAxesEditedImage = uiaxes(app.GridLayout5);
            title(app.UIAxesEditedImage, 'Edited Image')
            xlabel(app.UIAxesEditedImage, '')
            ylabel(app.UIAxesEditedImage, '')
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.UIAxesOriginalHistogram = uiaxes(app.GridLayout6);
            title(app.UIAxesOriginalHistogram, 'Original Histogram')
            xlabel(app.UIAxesOriginalHistogram, 'X')
            ylabel(app.UIAxesOriginalHistogram, 'Y')
            app.UIAxesEditedHistogram = uiaxes(app.GridLayout6);
            title(app.UIAxesEditedHistogram, 'EditedHistogram')
            xlabel(app.UIAxesEditedHistogram, 'X')
            ylabel(app.UIAxesEditedHistogram, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end